classdef interfaz2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ventana2                     matlab.ui.Figure
        PagPrincipalButton           matlab.ui.control.Button
        RaicesdeEcuacionesLabel      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        MetodosDropDownLabel         matlab.ui.control.Label
        MetodosDropDown              matlab.ui.control.DropDown
        ButtonGroup                  matlab.ui.container.ButtonGroup
        MetododelaSecanteButton      matlab.ui.control.RadioButton
        MetododeNewtonRaphsonButton  matlab.ui.control.RadioButton
        PuntoFijoButton              matlab.ui.control.RadioButton
        RaicesMultiplesButton        matlab.ui.control.RadioButton
        ContinuarButton              matlab.ui.control.Button
        ElijaelmtodoLabel            matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        MetododeBiseccinButton       matlab.ui.control.RadioButton
        MetododeFalsaPocisinButton   matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MetodosDropDown
        function MetodosDropDownValueChanged(app, event)
          app.ButtonGroup.Visible = 'on';
          if strcmp(app.MetodosDropDown.Value,'Abiertos')
              app.ButtonGroup.Visible = 'on';
              app.ButtonGroup_2.Visible = 'off';
          elseif strcmp(app.MetodosDropDown.Value,'Cerrados')
              app.ButtonGroup_2.Visible = 'on';
              app.ButtonGroup_2.Position = [368 170 197 54];
              app.ButtonGroup.Visible = 'off';
          end
         
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ventana2 = uifigure('Visible', 'off');
            app.ventana2.Name = 'MATLAB App';
            app.PagPrincipalButton = uibutton(app.ventana2, 'push');
            app.PagPrincipalButton.Text = 'Pag. Principal';
            app.RaicesdeEcuacionesLabel = uilabel(app.ventana2);
            app.RaicesdeEcuacionesLabel.Text = 'Raices de Ecuaciones';
            app.Label_2 = uilabel(app.ventana2);
            app.Label_2.Text = '---------------------------------------------------------------------------------------------------------------------------------';
            app.Image = uiimage(app.ventana2);
            app.Image.ImageSource = 'raices.png';
            app.MetodosDropDownLabel = uilabel(app.ventana2);
            app.MetodosDropDownLabel.Text = 'Metodos: ';
            app.MetodosDropDown = uidropdown(app.ventana2);
            app.MetodosDropDown.Items = {'Abiertos', 'Cerrados'};
            app.MetodosDropDown.ValueChangedFcn = createCallbackFcn(app, @MetodosDropDownValueChanged, true);
            app.MetodosDropDown.Value = 'Abiertos';
            app.ButtonGroup = uibuttongroup(app.ventana2);
            app.MetododelaSecanteButton = uiradiobutton(app.ButtonGroup);
            app.MetododelaSecanteButton.Text = 'Metodo de la Secante';
            app.MetododelaSecanteButton.Value = true;
            app.MetododeNewtonRaphsonButton = uiradiobutton(app.ButtonGroup);
            app.MetododeNewtonRaphsonButton.Text = 'Metodo de Newton-Raphson';
            app.PuntoFijoButton = uiradiobutton(app.ButtonGroup);
            app.PuntoFijoButton.Text = 'Punto Fijo';
            app.RaicesMultiplesButton = uiradiobutton(app.ButtonGroup);
            app.RaicesMultiplesButton.Text = 'Raices Multiples';
            app.ContinuarButton = uibutton(app.ventana2, 'push');
            app.ContinuarButton.Text = 'Continuar';
            app.ElijaelmtodoLabel = uilabel(app.ventana2);
            app.ElijaelmtodoLabel.Text = 'Elija el método';
            app.Label_3 = uilabel(app.ventana2);
            app.Label_3.Text = '---------------------------------------------------------------------------------------------------------------------------------';
            app.ButtonGroup_2 = uibuttongroup(app.ventana2);
            app.MetododeBiseccinButton = uiradiobutton(app.ButtonGroup_2);
            app.MetododeBiseccinButton.Text = 'Metodo de Bisección';
            app.MetododeBiseccinButton.Value = true;
            app.MetododeFalsaPocisinButton = uiradiobutton(app.ButtonGroup_2);
            app.MetododeFalsaPocisinButton.Text = 'Metodo de Falsa Pocisión';
            % Show the figure after all components are created
            app.ventana2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ventana2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ventana2)
        end
    end
end